classdef CalculateQ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Panel                 matlab.ui.container.Panel
        GridLayout            matlab.ui.container.GridLayout
        degreeLabel           matlab.ui.control.Label
        CloseButton           matlab.ui.control.Button
        UITable2              matlab.ui.control.Table
        UITable               matlab.ui.control.Table
        QMPaLabel             matlab.ui.control.Label
        QMPaatEditFieldLabel  matlab.ui.control.Label
        QMPaatEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        mainhan %handle for main app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainhan = mainapp;  %assigning main class object to handle of main class 
            %SO by "app.mainhan", we can read public property and function of main app 
            
            app.UITable.Data = app.mainhan.Q;  %display Q matrix
            app.UITable2.Data = app.mainhan.Q1; %display transformed Q matrix
            app.QMPaatEditField.Value = app.mainhan.Theta;  %display angle by which Q is tranformed
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.mainhan.CalculateQButton.Enable = 'on';  %reactivate the botton
            delete(app)  %delete this window
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.mainhan.CalculateQButton.Enable = 'on'; %reactivate the botton
            delete(app) %delete this window
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.degreeLabel = uilabel(app.GridLayout);
            app.degreeLabel.Text = 'degree';
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.UITable2 = uitable(app.GridLayout);
            app.UITable2.ColumnName = '';
            app.UITable2.RowName = {};
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.QMPaLabel = uilabel(app.GridLayout);
            app.QMPaLabel.Text = 'Q (MPa)';
            app.QMPaatEditFieldLabel = uilabel(app.GridLayout);
            app.QMPaatEditFieldLabel.Text = 'Q(MPa) at';
            app.QMPaatEditField = uieditfield(app.GridLayout, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CalculateQ(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end