classdef Import_Data < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Panel                matlab.ui.container.Panel
        GridLayout           matlab.ui.container.GridLayout
        ImportButton         matlab.ui.control.Button
        FibreDropDown        matlab.ui.control.DropDown
        FibreDropDownLabel   matlab.ui.control.Label
        MatrixDropDown       matlab.ui.control.DropDown
        MatrixDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainhan % handle for main app
    end
    
    methods (Access = private)
        
        function assignf(app,Ef1,Ef2,nuf12,Gf12,Vf)
            %function to assign fibre properties
            app.mainhan.E_f1GPaEditField.Value = Ef1;
            app.mainhan.E_f2GPaEditField.Value=Ef2;
            app.mainhan.nu_f12EditField.Value = nuf12;
            app.mainhan.G_f12GPaEditField.Value = Gf12;
            app.mainhan.V_fEditField.Value = Vf;
        end
        
        function assignm(app,Em,nu_m,Gm)
            %function to assign matrix properties 
            app.mainhan.E_mGPaEditField.Value =Em;
            app.mainhan.nu_mEditField.Value=nu_m;
            app.mainhan.G_mGPaEditField.Value = Gm;
        end
        
        function assign_strength(app,Xt,Xc,Yt,Yc,S12,S23)
            %function to assign strength parameters
            app.mainhan.XMPaEditField.Value =Xt;
            app.mainhan.XMPaEditField_2.Value = Xc;
            app.mainhan.YMPaEditField.Value = Yt;
            app.mainhan.YMPaEditField_2.Value = Yc;
            app.mainhan.S12MPaEditField.Value = S12;
            app.mainhan.S23MPaEditField.Value = S23;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainhan = mainapp;  %assigning main class object to handle of main class 
            %SO by "app.mainhan", we can read public property and function of main app 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.mainhan.ImportDataButton.Enable = 'on'; %reactivating the button
            delete(app) %delete the app
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            %check for option select in fibre and matrix drop down menu, 
            % and accordingly assigning the properties
            
            if app.FibreDropDown.Value == "Carbon"
                assignf(app,232,15,0.279,24,0.5);   %order is Ef1,Ef2,nuf12,Gf12,Vf
            elseif app.FibreDropDown.Value == "AS4"
                assignf(app,225,15,0.2,15,0.5);
            elseif app.FibreDropDown.Value == "E-Glass 1200 tex"
                assignf(app,80,80,0.2,33.33,0.525);
            elseif app.FibreDropDown.Value == "T300"
                assignf(app,230,15,0.2,15,0.5);
            elseif app.FibreDropDown.Value == "E-Glass"
                assignf(app,72.4,72.4,0.2,30.2,0.5);
            end
            
            if app.MatrixDropDown.Value == "Epoxy-1"
                assignm(app,3.45,0.35,1.29);  %order is Em,nu_m,Gm
            elseif app.MatrixDropDown.Value == "Epoxy-2"
                assignm(app,5.35,0.35,1.29);
            elseif app.MatrixDropDown.Value == "3501-6 Epoxy"
                assignm(app,4.2,0.34,1.567);
            elseif app.MatrixDropDown.Value == "BSL914c Epoxy"
                assignm(app,4,0.35,1.481);
            elseif app.MatrixDropDown.Value == "DY063 Epoxy"
                assignm(app,3.35,0.35,1.24);
            end
            
            %here for 3 combinations of fibre and matrix, we assign a
            %strength parameter. But it ca be modified and can be done for
            %other combinations
            if (app.FibreDropDown.Value == "E-Glass 1200 tex") && (app.MatrixDropDown.Value == "3501-6 Epoxy")
                assign_strength(app,1300,790,40,150,75,75);
            
            elseif (app.FibreDropDown.Value == "AS4") && (app.MatrixDropDown.Value == "3501-6 Epoxy")
                assign_strength(app,1950,1480,48,200,79,79);
                
             elseif (app.FibreDropDown.Value == "T300") && (app.MatrixDropDown.Value == "BSL914c Epoxy")
                assign_strength(app,1500,900,27,200,80,80);
           
            end
            
            app.mainhan.ImportDataButton.Enable = 'on'; %reactivating the button
            delete(app)  %delete the app
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.ImportButton = uibutton(app.GridLayout, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.FibreDropDown = uidropdown(app.GridLayout);
            app.FibreDropDown.Items = {'Carbon', 'AS4', 'E-Glass 1200 tex', 'T300', 'E-Glass'};
            app.FibreDropDown.Value = 'E-Glass 1200 tex';
            app.FibreDropDownLabel = uilabel(app.GridLayout);
            app.FibreDropDownLabel.Text = 'Fibre';
            app.MatrixDropDown = uidropdown(app.GridLayout);
            app.MatrixDropDown.Items = {'Epoxy-1', 'Epoxy-2', '3501-6 Epoxy', 'BSL914c Epoxy', 'DY063 Epoxy'};
            app.MatrixDropDown.Value = '3501-6 Epoxy';
            app.MatrixDropDownLabel = uilabel(app.GridLayout);
            app.MatrixDropDownLabel.Text = 'Matrix';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Import_Data(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end