classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Panel                        matlab.ui.container.Panel
        CalculateABDButton           matlab.ui.control.Button
        symmetricCheckBox            matlab.ui.control.CheckBox
        LayupSequencesepeartedbycommaLabel  matlab.ui.control.Label
        Plot_QButton                 matlab.ui.control.Button
        Plot_PropButton              matlab.ui.control.Button
        CalculateQButton             matlab.ui.control.Button
        EffectivePropertiesButton    matlab.ui.control.Button
        SeparatedbyCommaNxNyNxyMxMyMxyLabel  matlab.ui.control.Label
        StressesButton               matlab.ui.control.Button
        ImportDataButton             matlab.ui.control.Button
        RvaluesButton                matlab.ui.control.Button
        ForQtransformationLabel      matlab.ui.control.Label
        E_f1GPaEditField             matlab.ui.control.NumericEditField
        E_f1GPaEditFieldLabel        matlab.ui.control.Label
        E_f2GPaEditField             matlab.ui.control.NumericEditField
        E_f2GPaEditFieldLabel        matlab.ui.control.Label
        nu_f12EditField              matlab.ui.control.NumericEditField
        nu_f12EditFieldLabel         matlab.ui.control.Label
        G_f12GPaEditField            matlab.ui.control.NumericEditField
        E_mGPaEditField              matlab.ui.control.NumericEditField
        E_mGPaEditFieldLabel         matlab.ui.control.Label
        nu_mEditField                matlab.ui.control.NumericEditField
        nu_mEditFieldLabel           matlab.ui.control.Label
        G_mGPaEditField              matlab.ui.control.NumericEditField
        G_mGPaEditFieldLabel         matlab.ui.control.Label
        V_fEditField                 matlab.ui.control.NumericEditField
        V_fEditFieldLabel            matlab.ui.control.Label
        G_f12GPaEditFieldLabel       matlab.ui.control.Label
        ThetaindegEditField          matlab.ui.control.NumericEditField
        ThetaindegEditFieldLabel     matlab.ui.control.Label
        thetaindegreeEditField       matlab.ui.control.EditField
        thetaindegreeEditFieldLabel  matlab.ui.control.Label
        TotalThicknessHmmEditField   matlab.ui.control.NumericEditField
        TotalThicknessHmmEditFieldLabel  matlab.ui.control.Label
        NinNmmEditFieldLabel         matlab.ui.control.Label
        NinNmmEditField              matlab.ui.control.EditField
        MinNmmmmLabel                matlab.ui.control.Label
        MinNmmmmEditField            matlab.ui.control.EditField
        XMPaEditFieldLabel           matlab.ui.control.Label
        XMPaEditField                matlab.ui.control.NumericEditField
        XMPaEditField_2Label         matlab.ui.control.Label
        XMPaEditField_2              matlab.ui.control.NumericEditField
        YMPaEditFieldLabel           matlab.ui.control.Label
        YMPaEditField                matlab.ui.control.NumericEditField
        YMPaEditField_2Label         matlab.ui.control.Label
        YMPaEditField_2              matlab.ui.control.NumericEditField
        S12MPaEditFieldLabel         matlab.ui.control.Label
        S12MPaEditField              matlab.ui.control.NumericEditField
        S23MPaEditFieldLabel         matlab.ui.control.Label
        S23MPaEditField              matlab.ui.control.NumericEditField
        UDCOMPOSITELabel             matlab.ui.control.Label
        StrengthParametersLabel      matlab.ui.control.Label
    end


    properties (Access = public)
        %They are the public properties which can be access by this class(app) and other class
        
        %Effective properties of Uni-directional Composite. 'g' stands for GPa
        E1,E1g,E2,E2g,nu12,G12,G12g
        
        %Q : Stiffness Matrix  S:Compliance Matrix  Q1:Transformed Stiffness Matrix at Theta angle
        Q,Q1,S
        
        Theta          %Theta for Q matrix transformation
        
        %U = [A,B;B,D]
        %A:laminate-membrane stiffness. B:membrane-bending coupling stiffness.  D:laminate-bending stiffness
        A,B,D,U
        
        theta        %layup sequence in degree
        H,no_l,tk   %H: thickness of complete laminate, no_l: number of layers, tk: thickness of each lamina.
        %thickness of each lamina are taken equal
        z,z_mid %z co-ordinates of each layer.   z_mid : mid-point co-ordinates of each layer
        
        N,M  %N:load per unit length(Nx,Ny,Nxy).  M:Moment per unit length (Mx,My,Mxy)
        
        sigma_xx, sigma_xy,sigma_yy  %stresses in global cordinate system in MPa
        sigma_11, sigma_12,sigma_22  %stresses in local cordinate system  in MPa
        
        XT,XC,YT,YC,S12,S23   %strength of composite in MPa
        %XT,XC :longitudinal tensile & compressive strength
        %YT,YC :transverse tensile & compressive strength
        %S12, S23: in-plane and transverse shear strength
        
        Mat,Fib,R  %Mat,Fib:contain information about failure
        %R = margin of safety
    end


    properties (Access = private)
        %These all are handles created for other app which are called by this main app
        
        importhan   %IMport_Data app
        effprophan %effective properties app
        calculateQhan %calculate Q
        plot_prophan %plot properties
        plot_Qhan  %plot Q
        cal_ABDhan  %calculate A,B,D matrices
        cal_stresshan %clculates stress
        R_valuehan  %calculate R-values
    end


    methods (Access = private)
        %These are the function which can be called by function of this app(class) only.
        
        
        function effpropud(app)
            %reading input
            E_f1 = app.E_f1GPaEditField.Value;
            E_f2 = app.E_f2GPaEditField.Value;
            nu_f12 = app.nu_f12EditField.Value;
            G_f12= app.G_f12GPaEditField.Value;
            E_m = app.E_mGPaEditField.Value;
            nu_m = app.nu_mEditField.Value;
            G_m = app.G_mGPaEditField.Value;
            V_f = app.V_fEditField.Value;
            
            V_m = 1-V_f;            %Volume fraction of matrix
            
            %Effective Young modulus of composite in longitudinal direction
            app.E1g = V_f*E_f1 + V_m*E_m;
            
            if E_f2~=0
                %Effective Young modulus of composite in transverse direction
                app.E2g = inv(V_f/E_f2 + V_m/E_m);
            else
                app.E2g = E_m/V_m;          %in case of voids, E2=0
            end
            
            %Effective in-plane Poisson's ratio of composite
            app.nu12 = V_f*nu_f12 + V_m*nu_m;
            
            if G_f12~=0
                %Effective in-plane Shear modulus of composite
                app.G12g = inv(V_f/G_f12 + V_m/G_m);
            else
                app.G12g=G_m/V_m;
            end
            
            xlswrite("Result_File.xlsx",[app.E1g,app.E2g,app.nu12,app.G12g]',1,"B4");  %writing result in excel file
            xlswrite("Result_File.xlsx",["E1(GPa)","E2(GPa)","v12","G12(GPa)"]',1,"A4");
            %conversion of properties in MPa
            app.E1 = app.E1g*1000;
            app.E2 = app.E2g*1000;
            app.G12 = app.G12g*1000;
        end


        function calculateQ(app)
            %it will calculate Q matrix with help of effective properties calculated by effpropud function
            
            %calculating compliance matrix
            app.S = [
                1/app.E1             -app.nu12/app.E1     0
                -app.nu12/app.E1     1/app.E2            0
                0                  0                 1/app.G12
                ];
            
            %Q = inverse(S);
            app.Q = inv(app.S);
            
            xlswrite("Result_File.xlsx",app.Q,1,"E5");  %writing result in excel file
            xlswrite("Result_File.xlsx","Q",1,"F4");
            
            
        end


        function [T1,T2] = transform_matrices(~,m,n)
            %it will calculate transformation matrices for stress and strains
            % m =cos(theta) and n=sin(theta)
            
            %T1 is stress transformation matrix
            % sigma_local = T1*sigma_global or T1*sigma_xysystem
            T1 = [
                m^2      n^2     2*m*n
                n^2      m^2     -2*m*n
                -m*n     m*n     m^2-n^2
                ];
            
            %T2 is strain transformation matrix
            %strain_local = T2*strain_global
            T2 = [
                m^2      n^2     m*n
                n^2      m.^2     -m*n
                -2*m*n     2*m*n     m^2-n^2
                ];
            
        end


        function calculate_ABD(app)
            %it will calculate the A,B,D matrices
            
            th = app.thetaindegreeEditField.Value;  %reading layup sequence in string format
            th = str2double(strsplit(th,','));     %converting string format to double format
            if app.symmetricCheckBox.Value ==1   %checking symmetric checkbox is ticked or not
                app.theta = [th,flip(th)];      %in case of symmetric, flipping layup sequence and adding it
            else
                app.theta=th;   %in case of unsymmetric, input layup sequence is final
            end
            
            app.no_l=length(app.theta);  %number of layer based on theta vector length
            app.H = app.TotalThicknessHmmEditField.Value;   %reading input for H
            app.tk = app.H/app.no_l;  %calculating each lamina thickess
            app.z=(-app.no_l/2:app.no_l/2)*app.tk;  %z-cordinates of top & bottom of each lamina in mm
            app.A=zeros(3);   app.B=zeros(3);   app.D=zeros(3); %inintialize A,B,D matrix
            
            for i=1:app.no_l
                Q_bar=calculateQ_at_angle(app,app.theta(i));  %transforming Q matrix for each layer one by one
                %adding contribution of each layer in A,B,D matrices
                app.A = app.A+Q_bar*(app.z(i+1)-app.z(i));
                app.B=app.B+1/2*Q_bar*(app.z(i+1)^2-app.z(i)^2);
                app.D=app.D+1/3*Q_bar*(app.z(i+1)^3-app.z(i)^3);
            end
            %calculating U matrix.
            app.U=[app.A,app.B;app.B,app.D];
            
            xlswrite("Result_File.xlsx",app.A,1,"A11"); %writing result in excel file
            xlswrite("Result_File.xlsx",app.B,1,"E11");
            xlswrite("Result_File.xlsx",app.D,1,"I11");
            xlswrite("Result_File.xlsx",app.U,1,"B17");
            xlswrite("Result_File.xlsx","A",1,"B10");
            xlswrite("Result_File.xlsx","B",1,"F10");
            xlswrite("Result_File.xlsx","D",1,"J10");
            xlswrite("Result_File.xlsx","U",1,"D16");
        end


        function calculate_stresses(app)
            %it will calculate stress in all layers
            load = app.NinNmmEditField.Value;      %reading load/length input
            app.N = str2double(strsplit(load,','));
            moment = app.MinNmmmmEditField.Value;  %reading moment/length input
            app.M = str2double(strsplit(moment,','));
            app.N=app.N';app.M=app.M';    %transpose the loads and moments to make them 3*1 column vector
            
            %mid-point z-co-ordinates of each layer
            app.z_mid = app.z +app.tk/2; app.z_mid(end) = [];
            
            E0 = app.U\[app.N;app.M];   %lamimate strain
            e0 = E0(1:3);  %mid-surface strain
            k = E0(4:6);  %mid-surface curvature
            
            %initializing stresses
            app.sigma_xx=zeros(1,app.no_l);app.sigma_yy=zeros(1,app.no_l);app.sigma_xy=zeros(1,app.no_l);
            app.sigma_11=zeros(1,app.no_l);app.sigma_22=zeros(1,app.no_l);app.sigma_12=zeros(1,app.no_l);
            
            %for i th lamina
            for ii=1:app.no_l
                Q_bar=calculateQ_at_angle(app,app.theta(ii)); %transforming Q matrix for ith layer
                
                %cos(theta) and sin(theta) for the ith layer
                m=cosd(app.theta(ii));
                n=sind(app.theta(ii));
                
                %stress transformation matrix for ith layer
                T1 = transform_matrices(app,m,n);
                e= e0+app.z_mid(ii)*k; %total strain in ith layer
                
                sigma_bar = Q_bar*e;  %stresses in global system for ith layer
                
                %sigma_xx is vector array containing sigmaxx of each layer
                app.sigma_xx(ii)=sigma_bar(1);
                app.sigma_yy(ii)=sigma_bar(2);
                app.sigma_xy(ii)=sigma_bar(3);
                
                sigma_axis = T1*sigma_bar;  %stresses in its local co-ordinate system for ith layer
                
                %sigma_11 is vector array containing sigma11 of each layer
                app.sigma_11(ii)=sigma_axis(1);
                app.sigma_22(ii)=sigma_axis(2);
                app.sigma_12(ii)=sigma_axis(3);
            end
            
            xlswrite("Result_File.xlsx",[(1:app.no_l)',app.theta',app.sigma_xx',app.sigma_yy',app.sigma_xy',app.sigma_11',app.sigma_22',app.sigma_12'],1,"N6"); %write result in excel file
            xlswrite("Result_File.xlsx",["S. No","Angle (deg)", "sigma_xx(MPa)","sigma_yy(MPa)","sigma_xy(MPa)","sigma_11(MPa)","sigma_22(MPa)","sigma_12(MPa)"],1,"N5");
            
        end


        function [Mat_k,Fib_k,R_k] = hashin_failure(app,sigma_kth)
            %it will check for failure of one ply on basis of Hashin Failure Criteria. We need to call it n number of times for n layers
            
            %sigma_kth is 1*3 vector and its elements are [sigma11,sigma22,sigma12]
            
            %reading strength inputs
            app.XT = app.XMPaEditField.Value;
            app.XC = app.XMPaEditField_2.Value;
            app.YT = app.YMPaEditField.Value;
            app.YC = app.YMPaEditField_2.Value;
            app.S12 = app.S12MPaEditField.Value;
            app.S23 = app.S23MPaEditField.Value;
            
            %mtfm,mcfm are LHS of matrix tensile and compressive failure mode of Hashin failure
            %tffm,cffm are LHS of fibre tensile and compressive failure mode of Hashin failure
            mtfm=0;mcfm=0;tffm=0;cffm=0;
            
            %MATRIX FAILURE MODE
            if sigma_kth(2)>=0
                %matrix tensile failure mode
                mtfm =  (sigma_kth(2)/app.YT)^2 +(sigma_kth(3)/app.S12)^2;
                R1 = (1/mtfm)^0.5;
            elseif sigma_kth(2)<0
                %matrix compressive failure mode
                b=((-app.YC/(2*app.S23))^2 -1)*sigma_kth(2)/(-app.YC);        %Negative Sign with YC as it is compressive strength
                a = (sigma_kth(2)/(2*app.S23))^2 +(sigma_kth(3)/app.S12)^2;
                mcfm = a+b;
                %quadratic equation in terms of R,  a*R^2 +b*R-1=0
                x1 = (-b+(b^2 +4*a)^0.5)/(2*a); x2 = (-b-(b^2 +4*a)^0.5)/(2*a);
                if min(x1,x2)<0   %margin of safety must be minimum of 2 roots of quadratic equation but it can't be negative
                    R1 = max(x1,x2);
                else
                    R1 = min(x1,x2);
                end
            end
            
            %FIBRE FAILURE MODE
            if sigma_kth(1)>=0
                %Tensile fibre failure mode
                tffm = (sigma_kth(1)/app.XT)^2 +(sigma_kth(3)/app.S12)^2;
                R2 = (1/tffm)^0.5;
            elseif sigma_kth(1)<0
                %Compressive fibre failure mode
                cffm = (sigma_kth(1)/(-app.XC))^2;
                R2 = (1/cffm)^0.5;
            end
            
            %Fib_k & Mat_k will be 1 for tensile failure mode, -1 for compressive failure modes and 0 for no failure
            Fib_k=0;Mat_k=0;
            if mtfm>=1
                Mat_k=1;  %tensile matrix failure mode
            elseif mcfm>=1
                Mat_k=-1; %compressive matrix failure mode
            end
            
            if tffm>=1
                Fib_k=1;   %tensile fibre failure mode
            elseif cffm>=1
                Fib_k=-1;  %compressive fibre failure mode
            end
            
            if min(R1,R2)<0   %R should be minimum of both R1 & R2 but it can't be negative
                R_k=max(R1,R2);
            else
                R_k=min(R1,R2);
            end
        end


        function ply_failure(app)
            %it will check for every layer failure by calling Hasin_Failure function for each layer
            
            app.Mat=zeros(1,app.no_l); app.Fib=zeros(1,app.no_l); app.R=zeros(1,app.no_l);
            for ii=1:app.no_l
                %for ith ply
                sigma_i = [app.sigma_11(ii),app.sigma_22(ii),app.sigma_12(ii)]; %local stresses for ith layer
                [app.Mat(ii),app.Fib(ii),app.R(ii)] = hashin_failure(app,sigma_i); %checking failure of ith layer
            end
            
            xlswrite("Result_File.xlsx","R",1,"V5");
            xlswrite("Result_File.xlsx",app.R',1,"V6");
        end
    end


    methods (Access = public)
        %these are function which can be called by function of other app(class)


        function [E_x,E_y,G_xy,v_xy,nxy_x,nxy_y] = prop_at_angle(app,thet)
            %it will calclate properties of composite at input angle 'thet' when calling this function
            m=cosd(thet);
            n=sind(thet);
            
            %transformation matrices.
            %T1:stress transformation matrix, T2:strain transformation matrix
            [T1,T2] =transform_matrices(app,m,n);
            
            %compliance matrix transformation.  S_bar = (T_strain)^-1*S_local*T_stress . Here ^-1 mean matrix nversion
            S1 = T2\(app.S*T1);
            
            %these are in MPa
            E_x=1/S1(1,1);  %Young's modulus in x-direction
            E_y=1/S1(2,2);  %Young's modulus in y-direction
            G_xy = 1/S1(3,3); %in-plane shear modulus
            v_xy = -S1(1,2)*E_x; %inplane poisson's ratio
            
            %shear-coupling ratio
            nxy_x = S1(1,3)*E_x;
            nxy_y = S1(2,3)*E_y;
            
        end


        function [ E1_bar ,G_12bar,nu_12bar,eta12_1bar] = plot_prop_angles(app)
            %it will form the data for plotting the properties as function of theta
            
            t=0:90; nn=length(t); %theta varry from 0 to 90 degree
            E1_bar =zeros(1,nn);G_12bar=zeros(1,nn);nu_12bar=zeros(1,nn);eta12_1bar=zeros(1,nn);
            j=0;
            for i=0:90
                %calcualting properties at angles and storing in array
                [E1_bar(1,j+1),~,G_12bar(1,j+1),nu_12bar(1,j+1),eta12_1bar(1,j+1),~] = prop_at_angle(app,i);
                j=j+1;
            end
        end


        function Q2 = calculateQ_at_angle(app,thet)
            %it will transform the Q matrix at 'thet' degree which is input to this function
            
            m=cosd(thet);
            n=sind(thet);
            
            %stress and strain transformatio matrices
            [T1,T2] = transform_matrices(app,m,n);
            
            %stiffness, Q matrix transformation.  Q_bar = (T_stress)^-1*Q_local*T_strain . Here ^-1 mean matrix nversion
            Q2 = (T1)\(app.Q*T2);
            
        end


        function [sigmaxx,sigmayy,sigmaxy] = plot_stresses_pub(app)
            %it will form the data for stresses plotting
            
            %if we want to plot stress distribution, then on the interface of any 2 lamina, we need to calculate stresses 2 times
            %i.e. one with upper layer stiffness or Q matrix and one with lower layer stiffness or Q matrix
            
            %implementing the above written statement
            z1 = repelem(app.z,2); z1 =z1(2:end-1);
            th = repelem(app.theta,2);
            
            %calculating strains
            E0 = app.U\[app.N;app.M];  e0 = E0(1:3); k = E0(4:6);
            sigmaxx=zeros(1,length(z1));sigmayy=zeros(1,length(z1));sigmaxy=zeros(1,length(z1));
            for ii=1:length(z1)   %for a n-layer laminate, z1 will be 1*2n vector
                %transforming stiffness matrix
                Q_bar=calculateQ_at_angle(app,th(ii));
                
                e= e0+z1(ii)*k;
                %streses in global co-ordinate system
                sigma_bar = Q_bar*e;
                sigmaxx(ii)=sigma_bar(1);
                sigmayy(ii)=sigma_bar(2);
                sigmaxy(ii)=sigma_bar(3);
            end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EffectivePropertiesButton
        function EffectivePropertiesButtonPushed(app, event)
            
            %it will activate when user pressed "Effective Properties" Button
            
            effpropud(app);  %calling function
            
            app.EffectivePropertiesButton.Enable = 'off';  %deactivating the button, it will be reactivated when user close the pop-up window
            
            app.effprophan = eff_prop(app);  %calling another app : eff_prop
            
            
            %% IT WILL POP-UP A WINDOW IN WHICH EFFETIVE PROPERTIES OF UNIDIRECTIONAL COMPOSITE WILL BE LISTED %%
        end

        % Button pushed function: CalculateQButton
        function CalculateQButtonPushed(app, event)
            %it will activate when user pressed "Calculate Q"  Button
            
            %calling function so that required data is calculated and can be used by the other app
            effpropud(app);
            calculateQ(app);
            app.Theta = app.ThetaindegEditField.Value;  %taking input for Q transformation
            app.Q1 = calculateQ_at_angle(app,app.Theta);   %transforming Q matrix
            
            xlswrite("Result_File.xlsx",app.Q1,1,"I5");
            xlswrite("Result_File.xlsx",app.Theta,1,"L4");
            xlswrite("Result_File.xlsx",["Q","transform"]',1,"J3");
            xlswrite("Result_File.xlsx",["Angle","deg"]',1,"L2");
            
            app.CalculateQButton.Enable = 'off'; %deactivating the button
            
            app.calculateQhan = CalculateQ(app);  %calling another app : CalculateQ
            
            %%IT WILL POP-UP A WINDOW WITH Q AND Q TRANFORMED MATRIX WILL BE TEHRE%%
        end

        % Button pushed function: Plot_PropButton
        function Plot_PropButtonPushed(app, event)
            %it will activate when user pressed "Calculate Q"  Button
            
            %calling function
            effpropud(app);
            calculateQ(app);
            
            app.Plot_PropButton.Enable = 'off';  %deactivating the button
            
            app.plot_prophan = Plot_prop(app);   %calling another app :Plot_prop
            
            %%OPEN A WINDOW WITH GRAPH WHICH HAS OPTIONS TO PLOT DIFFERENT PROPERTIES%%
        end

        % Button pushed function: Plot_QButton
        function Plot_QButtonPushed(app, event)
            %it will activate when user pressed "Plot Q" Button
            
            %calling function
            effpropud(app);
            calculateQ(app);
            
            app.Plot_QButton.Enable = 'off';  %deactivating the button
            
            app.plot_Qhan = plot_Q(app);  %calling another app : plot_Q
            
            %%OPEN A WINDOW WHICH HAS OPTIONS TO PLOT DIFFERENT COMPONENETS OF Q MATRIX%%
        end

        % Button pushed function: CalculateABDButton
        function CalculateABDButtonPushed(app, event)
            %it will activate when user pressed "Calculate A,B,D" Button
            
            %calling function
            effpropud(app);
            calculateQ(app);
            calculate_ABD(app);
            
            app.CalculateABDButton.Enable = 'off';  %deactivating the button
            
            app.cal_ABDhan = cal_ABD(app);  %calling another app : cal_ABD
            
            %%POP-UP A WINDOW WITH A,B,D MATRICES%%
        end

        % Button pushed function: StressesButton
        function StressesButtonPushed(app, event)
            %it will activate when user pressed "Stresses" Button
            
            %calling function
            effpropud(app);
            calculateQ(app);
            calculate_ABD(app);
            calculate_stresses(app);
            
            app.StressesButton.Enable = 'off';  %deactivating the button
            
            app.cal_stresshan = stresses_plywise(app);  %calling another app : stresses_plywise
            
            %%WINDOW WITH PLYWISE STRESSES IN GLOBAL AND LOCAL CO-ORDINATE SYSTEM%%
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            %it will activate when user pressed "Import Data" Button
            
            app.ImportDataButton.Enable = 'off'; %deactivating the button
            
            app.importhan = Import_Data(app);  %calling another app : Import_Data
            
            %%WINDOW WITH SOME FIBRE AND MATRIX OPTIONS. IT WILL IMPORT THE DATA TO MAINAPP WINDOW WHEN USER SELECT THE FIBRE AND MATRIX & PRESSED IMPORT BUTTON%%
        end

        % Button pushed function: RvaluesButton
        function RvaluesButtonPushed(app, event)
            %it will activate when user pressed "R values" Button
            
            %calling function
            effpropud(app);
            calculateQ(app);
            calculate_ABD(app);
            calculate_stresses(app);
            ply_failure(app);
            
            app.RvaluesButton.Enable = 'off';  %deactivating the button
            
            app.R_valuehan = R_ply(app);  %deactivating the button :R_ply
            
            %%WINDOW WITH VALUES OF R CORRESPONDING TO EACH LAYER%%
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %when user pressed the cross on top rhs corner.
            %It will close all the popup window and this main app
            
            delete(app.effprophan)
            delete(app.calculateQhan)
            delete(app.plot_prophan)
            delete(app.plot_Qhan)
            delete(app.cal_ABDhan)
            delete(app.importhan)
            delete(app.cal_stresshan)
            delete(app.R_valuehan)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.CalculateABDButton = uibutton(app.Panel, 'push');
            app.CalculateABDButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateABDButtonPushed, true);
            app.CalculateABDButton.Text = 'Calculate A,B,D';
            app.symmetricCheckBox = uicheckbox(app.Panel);
            app.symmetricCheckBox.Text = 'symmetric';
            app.LayupSequencesepeartedbycommaLabel = uilabel(app.Panel);
            app.LayupSequencesepeartedbycommaLabel.Text = 'Layup Sequence (sepearted by comma)';
            app.Plot_QButton = uibutton(app.Panel, 'push');
            app.Plot_QButton.ButtonPushedFcn = createCallbackFcn(app, @Plot_QButtonPushed, true);
            app.Plot_QButton.Text = 'Plot_Q';
            app.Plot_PropButton = uibutton(app.Panel, 'push');
            app.Plot_PropButton.ButtonPushedFcn = createCallbackFcn(app, @Plot_PropButtonPushed, true);
            app.Plot_PropButton.Text = 'Plot_Prop';
            app.CalculateQButton = uibutton(app.Panel, 'push');
            app.CalculateQButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateQButtonPushed, true);
            app.CalculateQButton.Text = 'Calculate Q';
            app.EffectivePropertiesButton = uibutton(app.Panel, 'push');
            app.EffectivePropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @EffectivePropertiesButtonPushed, true);
            app.EffectivePropertiesButton.Text = 'Effective Properties';
            app.SeparatedbyCommaNxNyNxyMxMyMxyLabel = uilabel(app.Panel);
            app.SeparatedbyCommaNxNyNxyMxMyMxyLabel.Text = {'Separated by Comma'; 'Nx, Ny, Nxy'; 'Mx, My, Mxy'};
            app.StressesButton = uibutton(app.Panel, 'push');
            app.StressesButton.ButtonPushedFcn = createCallbackFcn(app, @StressesButtonPushed, true);
            app.StressesButton.Text = 'Stresses';
            app.ImportDataButton = uibutton(app.Panel, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.RvaluesButton = uibutton(app.Panel, 'push');
            app.RvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @RvaluesButtonPushed, true);
            app.RvaluesButton.Text = 'R-values';
            app.ForQtransformationLabel = uilabel(app.Panel);
            app.ForQtransformationLabel.Text = 'For Q transformation';
            app.E_f1GPaEditField = uieditfield(app.Panel, 'numeric');
            app.E_f1GPaEditFieldLabel = uilabel(app.Panel);
            app.E_f1GPaEditFieldLabel.Text = 'E_f1 (GPa)';
            app.E_f2GPaEditField = uieditfield(app.Panel, 'numeric');
            app.E_f2GPaEditFieldLabel = uilabel(app.Panel);
            app.E_f2GPaEditFieldLabel.Text = 'E_f2 (GPa)';
            app.nu_f12EditField = uieditfield(app.Panel, 'numeric');
            app.nu_f12EditFieldLabel = uilabel(app.Panel);
            app.nu_f12EditFieldLabel.Text = 'nu_f12';
            app.G_f12GPaEditField = uieditfield(app.Panel, 'numeric');
            app.E_mGPaEditField = uieditfield(app.Panel, 'numeric');
            app.E_mGPaEditFieldLabel = uilabel(app.Panel);
            app.E_mGPaEditFieldLabel.Text = 'E_m (GPa)';
            app.nu_mEditField = uieditfield(app.Panel, 'numeric');
            app.nu_mEditFieldLabel = uilabel(app.Panel);
            app.nu_mEditFieldLabel.Text = 'nu_m';
            app.G_mGPaEditField = uieditfield(app.Panel, 'numeric');
            app.G_mGPaEditFieldLabel = uilabel(app.Panel);
            app.G_mGPaEditFieldLabel.Text = 'G_m (GPa)';
            app.V_fEditField = uieditfield(app.Panel, 'numeric');
            app.V_fEditFieldLabel = uilabel(app.Panel);
            app.V_fEditFieldLabel.Text = 'V_f';
            app.G_f12GPaEditFieldLabel = uilabel(app.Panel);
            app.G_f12GPaEditFieldLabel.Text = 'G_f12 (GPa)';
            app.ThetaindegEditField = uieditfield(app.Panel, 'numeric');
            app.ThetaindegEditFieldLabel = uilabel(app.Panel);
            app.ThetaindegEditFieldLabel.Text = 'Theta (in deg.)';
            app.thetaindegreeEditField = uieditfield(app.Panel, 'text');
            app.thetaindegreeEditFieldLabel = uilabel(app.Panel);
            app.thetaindegreeEditFieldLabel.Text = 'theta (in degree)';
            app.TotalThicknessHmmEditField = uieditfield(app.Panel, 'numeric');
            app.TotalThicknessHmmEditFieldLabel = uilabel(app.Panel);
            app.TotalThicknessHmmEditFieldLabel.Text = 'Total Thickness, H (mm)';
            app.NinNmmEditFieldLabel = uilabel(app.Panel);
            app.NinNmmEditFieldLabel.Text = 'N (in N/mm)';
            app.NinNmmEditField = uieditfield(app.Panel, 'text');
            app.MinNmmmmLabel = uilabel(app.Panel);
            app.MinNmmmmLabel.Text = 'M (in N-mm/mm)';
            app.MinNmmmmEditField = uieditfield(app.Panel, 'text');
            app.XMPaEditFieldLabel = uilabel(app.Panel);
            app.XMPaEditFieldLabel.Text = 'X (MPa)';
            app.XMPaEditField = uieditfield(app.Panel, 'numeric');
            app.XMPaEditField_2Label = uilabel(app.Panel);
            app.XMPaEditField_2Label.Text = 'X'' (MPa)';
            app.XMPaEditField_2 = uieditfield(app.Panel, 'numeric');
            app.YMPaEditFieldLabel = uilabel(app.Panel);
            app.YMPaEditFieldLabel.Text = 'Y (MPa)';
            app.YMPaEditField = uieditfield(app.Panel, 'numeric');
            app.YMPaEditField_2Label = uilabel(app.Panel);
            app.YMPaEditField_2Label.Text = 'Y'' (MPa)';
            app.YMPaEditField_2 = uieditfield(app.Panel, 'numeric');
            app.S12MPaEditFieldLabel = uilabel(app.Panel);
            app.S12MPaEditFieldLabel.Text = 'S12 (MPa)';
            app.S12MPaEditField = uieditfield(app.Panel, 'numeric');
            app.S23MPaEditFieldLabel = uilabel(app.Panel);
            app.S23MPaEditFieldLabel.Text = 'S23 (MPa)';
            app.S23MPaEditField = uieditfield(app.Panel, 'numeric');
            app.UDCOMPOSITELabel = uilabel(app.Panel);
            app.UDCOMPOSITELabel.Text = 'UD COMPOSITE ';
            app.StrengthParametersLabel = uilabel(app.Panel);
            app.StrengthParametersLabel.Text = 'Strength Parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end