classdef Plot_prop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Panel               matlab.ui.container.Panel
        GridLayout          matlab.ui.container.GridLayout
        PlotE_xButton       matlab.ui.control.Button
        PlotG_xyButton      matlab.ui.control.Button
        Plotnu_xyButton     matlab.ui.control.Button
        Ploteta_xy_xButton  matlab.ui.control.Button
        PlotAllButton       matlab.ui.control.Button
        CloseButton         matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mainhan %handle for main class
        
        %properties of this class
        E1_bar   %for young's modulus in x-direction
        G_12bar %for in-plane shear modulus
        nu_12bar  %for in-plane Poisson's ration
        eta12_1bar %shear-coupling 
        t %for theta variation
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainhan = mainapp;    %assigning main class object to handle of main class 
            %SO by "app.mainhan", we can read public property and function of main app
            
            app.t=0:90;  %theta varry from 0 to 90 degrees
            
            %calcualting arrays of properties as theta varry with help of function of main class
            [app.E1_bar ,app.G_12bar,app.nu_12bar,app.eta12_1bar] = plot_prop_angles(app.mainhan);
            
            %plot_prop_angles(app.mainhan) : this is main class function            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.mainhan.Plot_PropButton.Enable = 'on';  %reactivating the button
            delete(app)  %delete the dialog box
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.mainhan.Plot_PropButton.Enable = 'on';  %reactivating the button
            delete(app)  %delete the diaog box
            
        end

        % Button pushed function: PlotAllButton
        function PlotAllButtonPushed(app, event)
            %when PLot All button pressed, it will plot all the properties at once
            cla(app.UIAxes,"reset")
            yyaxis(app.UIAxes,"left")
            plot(app.UIAxes,app.t,app.E1_bar/(app.mainhan.E2),app.t,app.G_12bar/app.mainhan.G12,app.t,-app.eta12_1bar);
            xlabel(app.UIAxes,'\theta');
            ylabel(app.UIAxes,'E_x/E_2, G_{xy}/G_{12}, -\eta_{xy,x}');
            title(app.UIAxes,'Properties as function of \theta');
            
            hold(app.UIAxes,'on')
            
            yyaxis(app.UIAxes,"right")
            plot(app.UIAxes,app.t,app.nu_12bar);
            ylabel(app.UIAxes,' \nu_{xy}');
            legend(app.UIAxes,'E_x/E_2','G_{xy}/G_{12}','-\eta_{xy,x}','\nu_{xy}','Location','northeast');
           
            hold(app.UIAxes,'off')
        end

        % Button pushed function: PlotE_xButton
        function PlotE_xButtonPushed(app, event)
            %only plot E_x/E2 when Plot E_x button pressed
            cla(app.UIAxes,"reset")
            plot(app.UIAxes,app.t,app.E1_bar/(app.mainhan.E2));
            xlabel(app.UIAxes,'\theta');
            ylabel(app.UIAxes,'E_x/E_2');
            title(app.UIAxes,'E_x/E_2 vs \theta');
            legend(app.UIAxes,'E_x/E_2');
            
        end

        % Button pushed function: PlotG_xyButton
        function PlotG_xyButtonPushed(app, event)
            %plot only G_xy/G12 when Plot G_xy button pressed
            cla(app.UIAxes,"reset")
            plot(app.UIAxes,app.t,app.G_12bar/(app.mainhan.G12));
            xlabel(app.UIAxes,'\theta');
            ylabel(app.UIAxes,'G_{xy}/G_{12}');
            title(app.UIAxes,'G_{xy}/G_{12} vs \theta');
            legend(app.UIAxes,'G_{xy}/G_{12}');
            
        end

        % Button pushed function: Plotnu_xyButton
        function Plotnu_xyButtonPushed(app, event)
            %plot nu_xy when Plot nu_xy button pressed
            
            cla(app.UIAxes,"reset")
            plot(app.UIAxes,app.t,app.nu_12bar);
            xlabel(app.UIAxes,'\theta');
            ylabel(app.UIAxes,'\nu_{xy}');
            title(app.UIAxes,'\nu_{xy} vs \theta');
            legend(app.UIAxes,'\nu_{xy}');
            
        end

        % Button pushed function: Ploteta_xy_xButton
        function Ploteta_xy_xButtonPushed(app, event)
            %plot etaxy_x when Plot eya_xy_x Button pressed
            cla(app.UIAxes,"reset")
            plot(app.UIAxes,app.t,-app.eta12_1bar);
            xlabel(app.UIAxes,'\theta');
            ylabel(app.UIAxes,'-\eta_{xy,x}');
            title(app.UIAxes,'-\eta_{xy,x} vs \theta');
            legend(app.UIAxes,'-\eta_{xy,x}');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.PlotE_xButton = uibutton(app.GridLayout, 'push');
            app.PlotE_xButton.ButtonPushedFcn = createCallbackFcn(app, @PlotE_xButtonPushed, true);
            app.PlotE_xButton.Text = 'Plot E_x';
            app.PlotG_xyButton = uibutton(app.GridLayout, 'push');
            app.PlotG_xyButton.ButtonPushedFcn = createCallbackFcn(app, @PlotG_xyButtonPushed, true);
            app.PlotG_xyButton.Text = 'Plot G_xy';
            app.Plotnu_xyButton = uibutton(app.GridLayout, 'push');
            app.Plotnu_xyButton.ButtonPushedFcn = createCallbackFcn(app, @Plotnu_xyButtonPushed, true);
            app.Plotnu_xyButton.Text = 'Plot nu_xy';
            app.Ploteta_xy_xButton = uibutton(app.GridLayout, 'push');
            app.Ploteta_xy_xButton.ButtonPushedFcn = createCallbackFcn(app, @Ploteta_xy_xButtonPushed, true);
            app.Ploteta_xy_xButton.Text = 'Plot eta_xy_x';
            app.PlotAllButton = uibutton(app.GridLayout, 'push');
            app.PlotAllButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAllButtonPushed, true);
            app.PlotAllButton.Text = 'Plot All';
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Plot_prop(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end