classdef R_ply < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Panel        matlab.ui.container.Panel
        GridLayout   matlab.ui.container.GridLayout
        CloseButton  matlab.ui.control.Button
        TextArea     matlab.ui.control.TextArea
        UITable      matlab.ui.control.Table
    end

    
    properties (Access = private)
        mainhan  % handle for main class    
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainhan = mainapp; %assigning main class object to handle of main class 
            %SO by "app.mainhan", we can read public property and function of main app
            
            %accessing stresses calculated by main class with app.mainhan
            sigmaxx = (app.mainhan.sigma_xx); sigmayy = (app.mainhan.sigma_yy); sigmaxy = (app.mainhan.sigma_xy);
            sigma11 = (app.mainhan.sigma_11); sigma22 = (app.mainhan.sigma_22); sigma12 = (app.mainhan.sigma_12);
            
            nol = app.mainhan.no_l;  %number of layer
            thet = app.mainhan.theta; %layup sequence
            
            sno = 1:nol; %Serial number
            
            Mt = app.mainhan.Mat; Fb = app.mainhan.Fib;  %information about failure mode
            R1 =app.mainhan.R;  %margin of safety
            
            %list the orinetation angle, stresses and R values
            app.UITable.Data = [sno',thet',sigmaxx',sigmayy',sigmaxy',sigma11',sigma22',sigma12',R1'];
           
            %sorting the margin of safety
           [R1,ind]=sort(R1); 
            
            if any(R1<=1)    
                %when there is ply failure
                
                R_c = R1(R1<=1);   %checking how many plies fails
                index = ind(1:length(R_c));  %sequence of failed plies
                M_c = Mt(index);  %matrix failure mode information 
                F_c = Fb(index);  %fibre failure mode information 
                theta_c = thet(index);  %orientation of faile plies
            else         
                %when laminate is safe i.e. R>1 for all plies
                
                R_c = R1(R1==R1(1));   %ply which has lowest R but it is grater than 1
                index = ind(1:length(R_c)); %sequence of such plies
                theta_c = thet(index);  %orientaion of such plies
            end
            
            nn=length(index);  %totol number of plies failed or critical plies (plies with lowest R but R>1)
            b="";
            if R_c>1
                %when laminate is safe i.e. R>1 for all plies
                
                %for printing the information about plies which has lowest R
                a = sprintf("Our laminate is safe.\n\nAnd ply which will fail if loading increased %.4f times is :\n ",R_c(1));
                for ii=1:nn
                    b = b +sprintf("\nLayer number : %d , oriented at %d degree\n",index(ii),theta_c(ii));
                end
                
            else   
                %when there is ply failure
                 
                %for printing information about the failure
                a = sprintf("Total number of ply failure : %d\n\nDetail of ply failure is : \n",nn);
                
                for ii=1:nn
                 c="";d="";   
                 b = b +sprintf("\nLayer number : %d & R = %f and oriented at %d degree & mode of failure is :",index(ii),R_c(ii),theta_c(ii));            
                    if M_c(ii)==1
                        c = sprintf(" matrix tensile failure. ");
                    elseif M_c(ii)==-1
                        c = sprintf(" matrix compressive failure.  ");
                    end
                    if F_c(ii)==1
                        d = sprintf(" fibre tensile failure.  ");
                    elseif F_c(ii)==-1
                        d = sprintf(" fibre compressive failure.  ");
                    end
                    b=b+c+d;
                end
                
            end
            
            app.TextArea.Value = a +b;  %printing information in text area
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.mainhan.RvaluesButton.Enable = 'on';  %reactivate the button
          
            delete(app)  %delete the window
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.mainhan.RvaluesButton.Enable = 'on';  %reactivate the button
            delete(app)  %delete the window
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TextArea = uitextarea(app.GridLayout);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'No.'; 'Angle(deg)'; 'sig_xx (MPa)'; 'sig_yy(MPa)'; 'sig_xy(MPa)'; 'sig_11(MPa)'; 'sig_22(MPa)'; 'sig_12(MPa)'; 'R'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = R_ply(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end