classdef cal_ABD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Panel        matlab.ui.container.Panel
        GridLayout   matlab.ui.container.GridLayout
        UITable_4    matlab.ui.control.Table
        UITable_3    matlab.ui.control.Table
        UITable_2    matlab.ui.control.Table
        UITable      matlab.ui.control.Table
        CloseButton  matlab.ui.control.Button
        ANmmLabel    matlab.ui.control.Label
        BNLabel      matlab.ui.control.Label
        BNLabel_2    matlab.ui.control.Label
        DNmmLabel    matlab.ui.control.Label
        ULabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainhan % handle for main class 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainhan = mainapp; %assigning main class object to handle of main class 
            %SO by "app.mainhan", we can read public property and function of main app
            
            app.UITable.Data = app.mainhan.A;  %display A matrix
            app.UITable_2.Data = app.mainhan.B;  %display B matrix
            app.UITable_3.Data = app.mainhan.B;
            app.UITable_4.Data = app.mainhan.D; %display D matrix
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.mainhan.CalculateABDButton.Enable = 'on'; %reactivate the botton
            delete(app)  %delete this window
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.mainhan.CalculateABDButton.Enable = 'on'; %reactivate the botton
            delete(app)         %delete this window  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.UITable_4 = uitable(app.GridLayout);
            app.UITable_4.ColumnName = '';
            app.UITable_4.RowName = {};
            app.UITable_3 = uitable(app.GridLayout);
            app.UITable_3.ColumnName = '';
            app.UITable_3.RowName = {};
            app.UITable_2 = uitable(app.GridLayout);
            app.UITable_2.ColumnName = '';
            app.UITable_2.RowName = {};
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ANmmLabel = uilabel(app.GridLayout);
            app.ANmmLabel.Text = 'A (N/mm)';
            app.BNLabel = uilabel(app.GridLayout);
            app.BNLabel.Text = 'B (N)';
            app.BNLabel_2 = uilabel(app.GridLayout);
            app.BNLabel_2.Text = 'B (N)';
            app.DNmmLabel = uilabel(app.GridLayout);
            app.DNmmLabel.Text = 'D (N-mm)';
            app.ULabel = uilabel(app.GridLayout);
            app.ULabel.Text = 'U';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cal_ABD(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end