classdef eff_prop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Panel                 matlab.ui.container.Panel
        GridLayout            matlab.ui.container.GridLayout
        CloseButton           matlab.ui.control.Button
        E1GPaEditField        matlab.ui.control.NumericEditField
        E1GPaEditFieldLabel   matlab.ui.control.Label
        E2GPaEditField        matlab.ui.control.NumericEditField
        E2GPaEditFieldLabel   matlab.ui.control.Label
        nu12EditField         matlab.ui.control.NumericEditField
        nu12EditFieldLabel    matlab.ui.control.Label
        G12GPaEditField       matlab.ui.control.NumericEditField
        G12GPaEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainhan % handle for main class
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainhan = mainapp; %assigning main class object to handle of main class 
            %SO by "app.mainhan", we can read public property and function of main app 
            
            %display effective properties calculated in main app
            app.E1GPaEditField.Value = app.mainhan.E1g;
            app.E2GPaEditField.Value =app.mainhan.E2g;
            app.nu12EditField.Value = app.mainhan.nu12;
            app.G12GPaEditField.Value =app.mainhan.G12g;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.mainhan.EffectivePropertiesButton.Enable = 'on';  %reactivating the button
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.mainhan.EffectivePropertiesButton.Enable = 'on'; %reactivating the button
            delete(app)  % Delete the dialog box
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.E1GPaEditField = uieditfield(app.GridLayout, 'numeric');
            app.E1GPaEditFieldLabel = uilabel(app.GridLayout);
            app.E1GPaEditFieldLabel.Text = 'E1 (GPa)';
            app.E2GPaEditField = uieditfield(app.GridLayout, 'numeric');
            app.E2GPaEditFieldLabel = uilabel(app.GridLayout);
            app.E2GPaEditFieldLabel.Text = 'E2 (GPa)';
            app.nu12EditField = uieditfield(app.GridLayout, 'numeric');
            app.nu12EditFieldLabel = uilabel(app.GridLayout);
            app.nu12EditFieldLabel.Text = 'nu12';
            app.G12GPaEditField = uieditfield(app.GridLayout, 'numeric');
            app.G12GPaEditFieldLabel = uilabel(app.GridLayout);
            app.G12GPaEditFieldLabel.Text = 'G12 (GPa)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eff_prop(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end