classdef plot_Q < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Panel           matlab.ui.container.Panel
        GridLayout      matlab.ui.container.GridLayout
        CloseButton     matlab.ui.control.Button
        Plot_allButton  matlab.ui.control.Button
        Q26Button       matlab.ui.control.Button
        Q16Button       matlab.ui.control.Button
        Q66Button       matlab.ui.control.Button
        Q12Button       matlab.ui.control.Button
        Q11Button       matlab.ui.control.Button
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mainhan % handle for main class
        t  %for theta variation
        Q  %Q at angle
        nn %totoal number of theta points plotted
    end
    
    methods (Access = private)
        
        function Q11 = calQ11(app)
            %make a vector of (1,1) component of Q as theta varries
            Q11 = zeros(1,app.nn);
            for i=1:app.nn
                Q11(i) = app.Q{i}(1,1);
            end
        end
        function Q12 = calQ12(app)
            %make a vector of (1,2) component of Q as theta varries
            Q12 = zeros(1,app.nn);
            for i=1:app.nn
                Q12(i) = app.Q{i}(1,2);
            end
        end
        function Q66 = calQ66(app)
            %make a vector of (3,3) component of Q as theta varries
            Q66 = zeros(1,app.nn);
            for i=1:app.nn
                Q66(i) = app.Q{i}(3,3);
            end
        end
        function Q16 = calQ16(app)
            %make a vector of (1,3) component of Q as theta varries
            Q16 = zeros(1,app.nn);
            for i=1:app.nn
                Q16(i) = app.Q{i}(1,3);
            end
        end
        function Q26 = calQ26(app)
            %make a vector of (2,3) component of Q as theta varries
            Q26 = zeros(1,app.nn);
            for i=1:app.nn
                Q26(i) = app.Q{i}(2,3);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainhan = mainapp;%assigning main class object to handle of main class
            %SO by "app.mainhan", we can read public property and function of main app
            
            app.t = 0:90; app.nn=length(app.t); %data points of theta
            app.Q = cell(1,app.nn); %creating cell array of Q for storing Q at each angle/data point of theta
            
            for i=0:90
                %calculating Q at each angle and storing cell array
                app.Q{i+1} =  calculateQ_at_angle(app.mainhan,app.t(i+1));
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.mainhan.Plot_QButton.Enable = 'on'; %reactivate the button
            delete(app) %delete the window
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.mainhan.Plot_QButton.Enable = 'on'; %reactivate the button
            delete(app)   %delete the window
            
        end

        % Button pushed function: Q11Button
        function Q11ButtonPushed(app, event)
            %plotting (1,1) of Q as theta varry
            
            Q11 = calQ11(app);
            cla(app.UIAxes,"reset")
            plot(app.UIAxes,app.t,Q11);
            xlabel(app.UIAxes,'\theta');
            ylabel(app.UIAxes,'Q_{11} (in MPa)');
            title(app.UIAxes,'Q_{11} (in MPa) vs \theta')
            legend(app.UIAxes,'Q_{11}','Location',"northeast");
        end

        % Button pushed function: Q12Button
        function Q12ButtonPushed(app, event)
            %%plotting (1,2) of Q as theta varry
            
            Q12 = calQ12(app);
            cla(app.UIAxes,"reset")
            plot(app.UIAxes,app.t,Q12);
            xlabel(app.UIAxes,'\theta');
            ylabel(app.UIAxes,'Q_{12} (in MPa)');
            title(app.UIAxes,'Q_{12} (in MPa) vs \theta')
            legend(app.UIAxes,'Q_{12}','Location',"northeast");
        end

        % Button pushed function: Q66Button
        function Q66ButtonPushed(app, event)
            %plotting (3,3) of Q as theta varry
            
            Q66 = calQ66(app);
            cla(app.UIAxes,"reset")
            plot(app.UIAxes,app.t,Q66);
            xlabel(app.UIAxes,'\theta');
            ylabel(app.UIAxes,'Q_{66} (in MPa)');
            title(app.UIAxes,'Q_{66} (in MPa) vs \theta')
            legend(app.UIAxes,'Q_{66}','Location',"northeast");
        end

        % Button pushed function: Q16Button
        function Q16ButtonPushed(app, event)
            %plotting (1,3) of Q as theta varry
            
            Q16 = calQ16(app);
            cla(app.UIAxes,"reset")
            plot(app.UIAxes,app.t,Q16);
            xlabel(app.UIAxes,'\theta');
            ylabel(app.UIAxes,'Q_{16} (in MPa)');
            title(app.UIAxes,'Q_{16} (in MPa) vs \theta')
            legend(app.UIAxes,'Q_{16}','Location',"northeast");
        end

        % Button pushed function: Q26Button
        function Q26ButtonPushed(app, event)
            %plotting (2,3) of Q as theta varry
            
            Q26 = calQ26(app);
            cla(app.UIAxes,"reset")
            plot(app.UIAxes,app.t,Q26);
            xlabel(app.UIAxes,'\theta');
            ylabel(app.UIAxes,'Q_{26}');
            title(app.UIAxes,'Q_{26} (in MPa) vs \theta')
            legend(app.UIAxes,'Q_{26}','Location',"northeast");
        end

        % Button pushed function: Plot_allButton
        function Plot_allButtonPushed(app, event)
            %plotting all componenets of Q as theta varry
            
            Q11 = calQ11(app);Q12 = calQ12(app);Q66 = calQ66(app);Q16 = calQ16(app);Q26 = calQ26(app);
            cla(app.UIAxes,"reset")
            yyaxis(app.UIAxes,'left')
            plot(app.UIAxes,app.t,Q11);
            xlabel(app.UIAxes,'\theta');
            ylabel(app.UIAxes,'Q_{11} (in MPa)');
            title(app.UIAxes,' Q (in MPa) vs \theta');
            
            hold(app.UIAxes,'on')
            
            yyaxis(app.UIAxes,'right')
            plot(app.UIAxes,app.t,Q12,app.t,Q66,app.t,Q16,app.t,Q26);
            ylabel(app.UIAxes,'(Q_{12}  Q_{66} Q_{16} Q_{26}) in MPa');
            legend(app.UIAxes,'Q_{11}','Q_{12}','Q_{66}','Q_{16}','Q_{26}','Location',"northeast");
            
            hold(app.UIAxes,'off')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Plot_allButton = uibutton(app.GridLayout, 'push');
            app.Plot_allButton.ButtonPushedFcn = createCallbackFcn(app, @Plot_allButtonPushed, true);
            app.Plot_allButton.Text = 'Plot_all';
            app.Q26Button = uibutton(app.GridLayout, 'push');
            app.Q26Button.ButtonPushedFcn = createCallbackFcn(app, @Q26ButtonPushed, true);
            app.Q26Button.Text = 'Q26';
            app.Q16Button = uibutton(app.GridLayout, 'push');
            app.Q16Button.ButtonPushedFcn = createCallbackFcn(app, @Q16ButtonPushed, true);
            app.Q16Button.Text = 'Q16';
            app.Q66Button = uibutton(app.GridLayout, 'push');
            app.Q66Button.ButtonPushedFcn = createCallbackFcn(app, @Q66ButtonPushed, true);
            app.Q66Button.Text = 'Q66';
            app.Q12Button = uibutton(app.GridLayout, 'push');
            app.Q12Button.ButtonPushedFcn = createCallbackFcn(app, @Q12ButtonPushed, true);
            app.Q12Button.Text = 'Q12';
            app.Q11Button = uibutton(app.GridLayout, 'push');
            app.Q11Button.ButtonPushedFcn = createCallbackFcn(app, @Q11ButtonPushed, true);
            app.Q11Button.Text = 'Q11';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plot_Q(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end