classdef stresses_plywise < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Panel        matlab.ui.container.Panel
        GridLayout   matlab.ui.container.GridLayout
        UITable2_2   matlab.ui.control.Table
        UITable2     matlab.ui.control.Table
        UITable      matlab.ui.control.Table
        Panel_2      matlab.ui.container.Panel
        GridLayout2  matlab.ui.container.GridLayout
        CloseButton  matlab.ui.control.Button
        UIAxes_3     matlab.ui.control.UIAxes
        UIAxes_2     matlab.ui.control.UIAxes
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mainhan % handle for main class
        nol %number of layer
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainhan =mainapp; %assigning main class object to handle of main class 
            %SO by "app.mainhan", we can read public property and function of main app
            
            %accessing stresses calculated by main class using app.mainhan 
            sigmaxx = (app.mainhan.sigma_xx); sigmayy = (app.mainhan.sigma_yy); sigmaxy = (app.mainhan.sigma_xy);
            sigma11 = (app.mainhan.sigma_11); sigma22 = (app.mainhan.sigma_22); sigma12 = (app.mainhan.sigma_12);
            
            app.nol = app.mainhan.no_l; %number of layers
            thet = app.mainhan.theta; %layup sequence
            
            sno = 1:app.nol; %serial number
            app.UITable.Data = [sno',thet']; %list Sno and orientation in table
            app.UITable2.Data = [sigmaxx',sigmayy',sigmaxy'];  %list global stresses 
            app.UITable2_2.Data = [sigma11',sigma22',sigma12']; %list local stresses
            
            %for plotting of global stresses
            z = app.mainhan.z; z1=repelem(z,2);
            z1=z1(2:end-1);
            [sigmaxx1,sigmayy1,sigmaxy1] = plot_stresses_pub(app.mainhan);  %main class function
            plot(app.UIAxes,sigmaxx1,z1); %plotting sigma_xx
            plot(app.UIAxes_2,sigmayy1,z1); %plotting sigma_yy
            plot(app.UIAxes_3,sigmaxy1,z1); %plotting sigma_xy
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.mainhan.StressesButton.Enable = 'on';  %reactivate button
            delete(app);  %delete  the app
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.mainhan.StressesButton.Enable = 'on';  %reactivate button
            delete(app); %delete  the app
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.UITable2_2 = uitable(app.GridLayout);
            app.UITable2_2.ColumnName = {'sigma_11(MPa)'; 'sigma_22(MPa)'; 'sigma_12(MPa)'};
            app.UITable2_2.RowName = {};
            app.UITable2 = uitable(app.GridLayout);
            app.UITable2.ColumnName = {'sigma_xx(Mpa)'; 'sigma_yy(MPa)'; 'sigma_xy(MPa)'};
            app.UITable2.RowName = {};
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'S. No.'; 'Orientation(degree)'};
            app.UITable.RowName = {};
            app.Panel_2 = uipanel(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.CloseButton = uibutton(app.GridLayout2, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.UIAxes_3 = uiaxes(app.GridLayout2);
            title(app.UIAxes_3, '\sigma_{xy} vs z')
            xlabel(app.UIAxes_3, '\sigma_{xy} (MPa)')
            ylabel(app.UIAxes_3, 'z(mm)')
            app.UIAxes_3.YDir = 'reverse';
            app.UIAxes_2 = uiaxes(app.GridLayout2);
            title(app.UIAxes_2, '\sigma_{yy} vs z')
            xlabel(app.UIAxes_2, '\sigma_{yy} (MPa)')
            ylabel(app.UIAxes_2, 'z(mm)')
            app.UIAxes_2.YDir = 'reverse';
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, '\sigma_{xx} vs z')
            xlabel(app.UIAxes, '\sigma_{xx} (MPa)')
            ylabel(app.UIAxes, 'z(mm)')
            app.UIAxes.YDir = 'reverse';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stresses_plywise(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end